classdef waveTrackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        saveRecalcButton  matlab.ui.control.Button
        LoadFileButton    matlab.ui.control.Button
        Slider            matlab.ui.control.Slider
        SliderLabel       matlab.ui.control.Label
        imageWaveColAx    matlab.ui.control.UIAxes
        imageDFAx         matlab.ui.control.UIAxes
    end


    properties (Access = private)

    end

    properties (Access = public)
        disStack % Description
        exStruct
        mergeStruct
    end

    methods (Access = private)

        function app = loadImages(app)

            exStruct = app.exStruct;

            % get filepath strings
            dF_filepath = [exStruct.filePath(1:end-4) '_dF_F.tif'];
            waveColFilepath = [exStruct.filePath(1:end-4) '_waveCol.tif'];

            % load in dF images and waveCol Images
            disStack.dFTifStack = read_Tiffs(dF_filepath);
            disStack.dFTifStack = imadjustn(disStack.dFTifStack);
            disStack.waveColStack = tiffreadVolume(waveColFilepath);

            app.disStack = disStack;


            % set images and slider
            frame = 1;
            setImagesSlider(app, frame);
        end

        function results = setImagesSlider(app, frame)

            imagesc(app.imageDFAx,app.disStack.dFTifStack(:,:,frame));
            app.imageDFAx.Children.ButtonDownFcn = app.imageDFAx.ButtonDownFcn; % copy button down function to image

            imagesc(app.imageWaveColAx,squeeze(app.disStack.waveColStack(:,:,frame,:)));
            app.imageWaveColAx.Children.ButtonDownFcn = app.imageWaveColAx.ButtonDownFcn; % copy button down function to image

            app.Slider.Value = frame;

            plotDeleteBB(app);

        end

        function cellIndx = findClosestObj(app,clickPt)

            frame = app.Slider.Value;
            currentFrameObj = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.Frame == frame,:);

            currentXY = [currentFrameObj.Centroid];
            [d,indx] = pdist2(currentXY,clickPt,"euclidean","Smallest",1);

            % realign to full table
            frameIDs = find(app.exStruct.waves.waveTable.Frame == frame);

            cellIndx = frameIDs(indx);
        end

        function findDeleteIndxStart(app,cellIndx, clickType)
            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);


            % for each frame
            overlapFlag = waveObjs.DeleteFlag;
           
            for f = 1:height(waveObjs)
                overlap = bboxOverlapRatio(obOfInterest.BoundingBox, waveObjs.BoundingBox(f,:));

                if overlap > 0
                    overlapFlag(f) = 1;
                end
            end

            waveObjGrand.DeleteFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = overlapFlag;
            app.exStruct.waves.waveTable = waveObjGrand;

            plotDeleteBB(app);
        end

        function plotDeleteBB(app)

            frame = round(app.Slider.Value); % integer round
            waveObjGrand = app.exStruct.waves.waveTable;

            frameObj = waveObjGrand(waveObjGrand.Frame ==frame,:);

            for ob = 1:height(frameObj)

                if frameObj.DeleteFlag(ob) == 1
                    hold(app.imageWaveColAx,'on');
                    rectangle(app.imageWaveColAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'r')
                    hold(app.imageWaveColAx,'off');

                    hold(app.imageDFAx,'on');
                    rectangle(app.imageDFAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'r')
                    hold(app.imageDFAx,'off');

                end
            end
        end

        function findDeleteIndxEnd(app,cellIndx, clickType)

            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            frameEnd = obOfInterest.Frame;

            frameRemoveStart = find(waveObjs.Frame == frameEnd+1);
            waveObjs.DeleteFlag(frameRemoveStart:end) = 0;
            waveObjGrand.DeleteFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = waveObjs.DeleteFlag;

            app.exStruct.waves.waveTable = waveObjGrand;

            plotDeleteBB(app)
        end

        function findMergeIndxs(app,cellIndx)


        end

        function plotMergeBB(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                load([p f]);
                exStruct.filePath = fullfile(p,[f(1:end-13) '.nd2']);
                app.exStruct = exStruct;
                
                % load in DeleteFlag
                if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'DeleteFlag'))
                    app.exStruct.waves.waveTable.DeleteFlag = zeros(height(app.exStruct.waves.waveTable),1);
                end

                loadImages(app);

                % intial slider setup
                app.Slider.Limits = [1 length(exStruct.xyShifts)];
                app.Slider.Value = 1;
                app.SliderLabel.Text = f(1:end-13);

                
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            frame = round(app.Slider.Value); % integer round

            app.Slider.Value = frame; % set

            setImagesSlider(app, frame);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key

                case 'rightarrow' % increase frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame +1; % set

                    if newVal >app.Slider.Limits(2)
                        newVal = app.Slider.Limits(2);
                    end

                    setImagesSlider(app, newVal);

                case 'leftarrow' % decrease frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame -1; % set

                    if newVal < app.Slider.Limits(1)
                        newVal = app.Slider.Limits(1);
                    end

                    setImagesSlider(app, newVal)

                case 'space' % clear selection
                    app.exStruct.waves.waveTable.DeleteFlag = zeros(size(app.exStruct.waves.waveTable.DeleteFlag));
                    plotDeleteBB(app);

            end

        end

        % Window scroll wheel function: UIFigure
        function ScrollWheelCallbck(app, event)
            %             verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            frame = round(app.Slider.Value); % integer round
            newVal = frame  + verticalScrollCount; % set

            if newVal < app.Slider.Limits(1)
                newVal = app.Slider.Limits(1);
            elseif newVal >app.Slider.Limits(2)
                newVal = app.Slider.Limits(2);
            end

            setImagesSlider(app, newVal)

        end

        % Button down function: imageWaveColAx
        function imageWaveColAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % match wave object
            cellIndx = findClosestObj(app,clickPt);

            cntrlDw =  getAsyncKeyState(VirtualKeyCode.VK_CONTROL);

            if cntrlDw == 0

                switch event.Button

                    case 1
                        findDeleteIndxStart(app,cellIndx, event.Button);

                    case 3
                        findDeleteIndxEnd(app,cellIndx, event.Button);
                end
            else
                switch event.Button

                    case 1

                    case 3

                end
            end
        end

        % Button down function: imageDFAx
        function imageDFAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % match wave object
            cellIndx = findClosestObj(app,clickPt);

            cntrlDw =  getAsyncKeyState(VirtualKeyCode.VK_CONTROL);

            if cntrlDw == 0

                switch event.Button

                    case 1
                        findDeleteIndxStart(app,cellIndx, event.Button);

                    case 3
                        findDeleteIndxEnd(app,cellIndx, event.Button);
                end
            else
                switch event.Button

                    case 1

                    case 3

                end
            end

        end

        % Button pushed function: saveRecalcButton
        function saveRecalcButtonPushed(app, event)

            % remove deletes
            app.exStruct.waves.waveTable = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.DeleteFlag == 0,:);

            % renumber waves
            [~,~,newWaveNo] = unique(app.exStruct.waves.waveTable.waveNumber);
            app.exStruct.waves.waveTable.waveNumber = newWaveNo;

            [app.exStruct.waves.waveTable, newWaveColStack] = recalculateWaveMetrics(app);

            app.disStack.waveColStack = newWaveColStack;

            exStruct = app.exStruct;

            save([app.exStruct.filePath(1:end-4) '_ExStruct.mat'], 'exStruct');

            frame = round(app.Slider.Value); % integer round
            setImagesSlider(app, frame);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ScrollWheelCallbck, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.imageDFAx = uiaxes(app.UIFigure);
            app.imageDFAx.XLim = [0 512];
            app.imageDFAx.YLim = [0 512];
            app.imageDFAx.XTick = [];
            app.imageDFAx.YTick = [];
            app.imageDFAx.ZTick = [];
            app.imageDFAx.ButtonDownFcn = createCallbackFcn(app, @imageDFAxButtonDown, true);
            colormap(app.imageDFAx, 'gray')
            app.imageWaveColAx = uiaxes(app.UIFigure);
            app.imageWaveColAx.XLim = [0 512];
            app.imageWaveColAx.YLim = [0 512];
            app.imageWaveColAx.XTick = [];
            app.imageWaveColAx.YTick = [];
            app.imageWaveColAx.ZTick = [];
            app.imageWaveColAx.ButtonDownFcn = createCallbackFcn(app, @imageWaveColAxButtonDown, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load in Experiment Structure';
            app.saveRecalcButton = uibutton(app.UIFigure, 'push');
            app.saveRecalcButton.ButtonPushedFcn = createCallbackFcn(app, @saveRecalcButtonPushed, true);
            app.saveRecalcButton.Text = 'Recalculate and save Ex Structure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waveTrackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end